classdef web_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HomeMenu                   matlab.ui.container.Menu
        OpenfileMenu               matlab.ui.container.Menu
        matMenu                    matlab.ui.container.Menu
        h5Menu                     matlab.ui.container.Menu
        niMenu                     matlab.ui.container.Menu
        FIlterMenu                 matlab.ui.container.Menu
        MeanMenu                   matlab.ui.container.Menu
        ToolboxesMenu              matlab.ui.container.Menu
        PCAMenu                    matlab.ui.container.Menu
        BandSlider                 matlab.ui.control.Slider
        BandSliderLabel            matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        PCATab                     matlab.ui.container.Tab
        BacktooriginalButton_2     matlab.ui.control.Button
        SliderLabel                matlab.ui.control.Label
        Slider_PCA                 matlab.ui.control.Slider
        NumberofPCAEditField       matlab.ui.control.NumericEditField
        NumberofPCAEditFieldLabel  matlab.ui.control.Label
        axes_PCA                   matlab.ui.control.UIAxes
        InteractiveTurnonButton    matlab.ui.control.Button
        BacktooriginalButton       matlab.ui.control.Button
        image_axes                 matlab.ui.control.UIAxes
        spectra                    matlab.ui.control.UIAxes
        ContextMenu                matlab.ui.container.ContextMenu
        SaveasidcubeformatMenu     matlab.ui.container.Menu
        SaveasimageMenu            matlab.ui.container.Menu
    end

    
    % methods (Access = private)
    % 
    %     function results = mouse_move(app)
    % 
    %         C = get(app.image_axes,'currentpoint');
    %         x = round(C(1,1));
    %         y = round(C(1,2));
    % 
    %         xlim = get(  app.image_axes,'xlim');
    %         ylim = get(  app.image_axes,'ylim');
    %         outX = ~any(diff([xlim(1) C(1,1) xlim(2)])<0);
    %         outY = ~any(diff([ylim(1) C(1,2) ylim(2)])<0);
    %         if outX&&outY
    % 
    %             val_channel = reshape(mat(y,x,:),[1,dim(end)]);
    %             [I,b] = max(val_channel) ;
    %             [val,idx] = max(y) ;
    %             C = [x(idx) y(idx)] ;
    %             plot(x, y, '-o','MarkerSize',50/(NumberChannels+3),'LineWidth',1.5,'color', [0.0244, 0.435, 0.8755],'Parent', app.spectra);
    %             hold on
    % 
    %             plot(x(idx), y(idx),'o','MarkerSize',10,'LineWidth',1.0,'Parent', app.spectra)
    %             xlab='Band/Channel'; 
    %             xlabel(xlab, 'Color', fgc);
    %             ylabel('Intensity', 'Color', fgc);
    %         end
    %     end
    % end
    
    methods (Access = private)
        
        function results = mouse_move(app)

          % app=getappdata(0,'idcube');

            C = get(app.image_axes,'currentpoint');
            x = round(C(1,1));
            y = round(C(1,2));
            matrices=getappdata(0,'myData');
            mat = matrices.Images;
            wavelengths=matrices.Wavelengths;
            NumberChannels = numel(wavelengths);
%         mat = getappdata(0,'Image');
            dim = size(mat);
            xlim = get(  app.image_axes,'xlim');
            ylim = get(  app.image_axes,'ylim');
            outX = ~any(diff([xlim(1) C(1,1) xlim(2)])<0);
            outY = ~any(diff([ylim(1) C(1,2) ylim(2)])<0);
            if outX&&outY
             try
                val_channel = reshape(mat(y,x,:),[1,dim(end)]);
                [I,b] = max(val_channel) ;
                new_x=wavelengths(1,:);
                [val,idx] = max(val_channel) ;
                % C = [x(idx) y(idx)] ;
                plot(new_x, val_channel, '-o','MarkerSize',50/(NumberChannels+3),'LineWidth',1.5,'color', [0.0244, 0.435, 0.8755],'Parent', app.spectra);
                % hold on

                % plot(new_x(idx), val_channel(idx),'o','MarkerSize',10,'LineWidth',1.0)
                xlab='Band/Channel'; 
                % xlabel(xlab);
                % ylabel('Intensity');
                % grid on
                % hold off
             catch
             end
            end
            
        end


        function results = mean_apply(app)
            cutoff=   getappdayta(0,'cutoff');
            matrices=getappdata(0,'myData');
            denoised=medfilt3(matrices.Images, [cutoff cutoff  1]);
            denoised(isnan(denoised))=0;
%             display_waitbar(1,1,90,100, waitbarHandle);
            
            matrices.Images=im2double(denoised);

            I=matrices.Images(:,:,2);
            %axes(app.image_axes)
            imshow(I,[],'Parent', app.image_axes);
            colormap(app.image_axes,getappdata(0,'color'));
            b=length(matrices.Wavelengths);
            app.BandSlider.Limits =[1 b];
     
            colormap(jet)
            setappdata(0,'myData',matrices);

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: matMenu
        function OpenfileMenuSelected(app, event)

            [fileName,pathName,indx] = uigetfile({fullfile(pwd,'*.mat')},'Select .mat file','MultiSelect','on');
            path_filename = fullfile(pathName,fileName);
             x = load(path_filename);
                            x_field_length = length(fieldnames(x));
                            % display_waitbar(1,1,60,100, waitbarHandle);
                            y = struct2cell(x);
                            if x_field_length == 1
                                hsidata = y{1};
                                if isstruct(hsidata)
                                    hsidata = struct2cell(hsidata);
                                end
                                if length(hsidata)==2
                                    Image = hsidata{1};
                                    wavelength = hsidata{2};
                                else
                                    if ndims(hsidata)==3
                                        Image = hsidata;
                                        wavelength = 1:size(Image,3);
                                    else
                                        showErrorAndExit('Image data are not found. Please check the file.');
                                        return
                                    end
                                end
                                elseif x_field_length == 2
                                hsidata = y;
                                if ndims(hsidata{1})==3
                                    Image = hsidata{1};
                                    wavelength = hsidata{2};
                                else
                                    Image = hsidata{2};
                                    wavelength = hsidata{1};
                                end
                            
                            end

            %%%Save data

                  
                    Image(isnan(Image))=0;
                    wavelength(isnan(wavelength))=0;

                    % Store data into matrices handle
                    matrices.Images = im2double(Image);
                    matrices.Wavelengths = wavelength;
                    % display_waitbar(1,1,70,100, waitbarHandle);

                    setappdata(0,'myData',matrices);
                    setappdata(0,'myDataOriginal',matrices);
                    idcube.axes=app.image_axes;
                    
                    setappdata(0,'idcube',idcube)
                    %%

            I=Image(:,:,2);
            setappdata(0,'I',I) 
            % %axes(app.image_axes)
            imshow(I,[],'Parent', app.image_axes);
            % colormap(app.image_axes,getappdata(0,'color'));
            b=length(matrices.Wavelengths);
            app.BandSlider.Limits =[1 b];
            filename=split(fileName,'.mat');
            setappdata(0,filename{1},'file_name')
            % app.BacktooriginalButton.Visible='on';
            app.TabGroup.Visible='off';
            app.BandSlider.Visible='on';
            % % colormap(jet)
            % colorbar('HitTest','on','color',fgc);
        end

        % Value changing function: BandSlider
        function BandSliderValueChanged(app, event)
            value = round(app.BandSlider.Value);

               matrices=getappdata(0,'myData');
             Image = im2double(matrices.Images );
                    % display_waitbar(1,1,70,100, waitbarHandle);
             I=Image(:,:,value);
            axes(app.image_axes)
            imshow(I,[],'Parent', app.image_axes,'Colormap',jet);
            % colormap(jet)
        end

        % Button pushed function: InteractiveTurnonButton
        function InteractiveTurnonButtonPushed(app, event)
                 app.UIFigure.WindowButtonMotionFcn = @(src, event) app.mouse_move();
                  % set(gcf,'WindowButtonMotionFcn', @mouse_move)
                  

        end

        % Menu selected function: h5Menu
        function h5MenuSelected(app, event)
             [filename,pathname,findex] = uigetfile(fullfile(pwd,'*.h5*'),...
            'Select H5 data');
              % Retrieving information from the HDF5 file
              if ispc
                  fullfilename = [pathname,'\', filename];
              elseif ismac
                  fullfilename = [pathname,'/', filename];
              end
            info = hdf5info(fullfilename);
            T = struct2table(info);
            try
                data_image = info.GroupHierarchy.Datasets(1).Name;
                %     data_mask = info.GroupHierarchy.Datasets(2).Name;

                % Reading the image data from the HDF5 file

                image_image = hdf5read(fullfilename,data_image);
            catch

                data_image=info.GroupHierarchy.Groups.Groups.Datasets.Name; %(SWIR)
                data_image2=info.GroupHierarchy.Groups(1).Groups.Groups(1).Groups(1).Datasets(3).Name ; %VWIR
                image_image = hdf5read(fullfilename,data_image);
            end
            % Obtaining the names of the datasets from the HDF5 file
        %     data_image = info.GroupHierarchy.Datasets(1).Name;
        % %     data_mask = info.GroupHierarchy.Datasets(2).Name;
        % 
        %     % Reading the image data from the HDF5 file
        %     image_image = hdf5read(fullfilename,data_image);
            
            % Reordering the dimensions of the image data
            Image = permute(image_image,[3 2 1]);
            
            % Creating the XML file name with appropriate file separator based on the platform
            if ispc
                xmlfile = strcat(pathname,'\', filename,'.txt');
            elseif ismac
                xmlfile = strcat(pathname,'/', filename,'.txt');
            end
            
            % Opening the XML file for writing
            fid = fopen(xmlfile,'w');
            
            [r,c] = size(T);
            % Writing the contents of the HDF5 file's table to the XML file
            for i = 1:c
                try
                    col = T.Properties.VariableNames{i};
                    value = string(T{:,col});
                    p = strcat(col, ':', value);
                    fprintf(fid,'%s\n',p);
                catch
                end
            end
            fclose(fid);
            
            [r,c,b] = size(Image);
            wavelength = [1:b];
            %%%Save data

                    % 
                    % Image(isnan(Image))=0;
                    % wavelength(isnan(wavelength))=0;

                    % Store data into matrices handle
                    matrices.Images = im2double(Image);
                    matrices.Wavelengths = wavelength;
                    % display_waitbar(1,1,70,100, waitbarHandle);

                    setappdata(0,'myData',matrices);
                    setappdata(0,'myDataOriginal',matrices);
                    idcube.axes=app.image_axes;
                    
                    setappdata(0,'idcube',idcube)
                    %%

            I=Image(:,:,2);
            %axes(app.image_axes)
            imshow(I,[],'Parent', app.image_axes);
            colormap(app.image_axes,getappdata(0,'color'));
            b=length(matrices.Wavelengths);
            app.BandSlider.Limits =[1 b];
            filename=split(filename,'.h5');
            setappdata(0,filename{1},'file_name')
            app.TabGroup.Visible='off';
            app.BandSlider.Visible='on';
            colormap(jet)
        end

        % Menu selected function: niMenu
        function niMenuSelected(app, event)
           %% Nifti6y
             [filename,pathname,findex] = uigetfile(fullfile(pwd,'*.nii*'),...
            'Select NII data',current_folder);
            dataname = [pathname filename];
%         [file_path, filename] = fileparts(dataname);
        if ispc
            fullfilename = [dataname,'\' ];
        elseif ismac
            fullfilename = [dataname,'/'];
        end          
        dicm2nii(fullfilename,fullfilename,'nii');
        k=getappdata(0,'nfi_file');
        k=[fullfilename,k{1}];
        Image=double(niftiread(strcat(k,'.nii')));
        
        
        [r,c,b]=size(Image);
        wavelength=[1:b];
%         Wavelength=Wavelength';
%         in = inputdlg_id(prompt,dlgtitle,dims);
%         try
%         in=str2double(in{1,1});
        % waitbarHandle = waitbar(0,'Please wait...','name','Converting DICOM format to IDCUBE format and saving...');
        % display_waitbar(1,1,10,100, waitbarHandle);
        pause(0.5);
        
%         Image=permute(reshape(Image, r, c/in, in), [3 2 1]);
        
        
        matrices.Images = Image;
        matrices.Wavelengths = wavelength;
        I=Image(:,:,2);
        setappdata(0,'I',I) 
            %axes(app.image_axes)
        imshow(I,[],'Parent', app.image_axes);
        colormap(app.image_axes,getappdata(0,'color'));
        b=length(matrices.Wavelengths);
        app.BandSlider.Limits =[1 b];
        app.BandSlider.Visible='on';
     
        colormap(jet)
        setappdata(0,filename,'file_name')
        end

        % Menu selected function: MeanMenu
        function MeanMenuSelected(app, event)
                app2=app;
             matrices=getappdata(0,'myData');
            mean.fig = uifigure('Name', 'Spatial Filter Parameters',...
                    'NumberTitle', 'off', ...
                    'Position', [400 400 350 130], ... % Adjusted for absolute positioning in pixels
                    'WindowStyle', 'modal', ...
                    'Resize', 'off',...
                    'AutoResizeChildren', 'off', 'Icon', 'idcube-icon-transparent.png');
    
                % Color definition for buttons
                green_btn = [77, 194, 115]/255;
                white_text = [1, 1, 1];  % White text color
    
                % Filter cutoff text label
                cutoff_text = uilabel(mean.fig, ...
                    'Text', 'Filter cutoff (odd number)', ...
                    'BackgroundColor', mean.fig.Color,...
                    'Position', [50 65 140 39],...  % Adjust position and size accordingly
                    'FontSize', 10,...
                    'FontColor', white_text);
    
                % Input for filter cutoff
                cutoff = uieditfield(mean.fig, ...
                    'numeric', ...
                    'Position',[50 50 120 26],...
                    'Value', 3, ...
                    'BackgroundColor', [1 1 1]);
    
                % Apply button
                mean.apply = uibutton(mean.fig,...
                    'Position', [250 20 80 30],...
                    'Text', 'Apply', ...
                    'BackgroundColor', green_btn, ...
                    'FontColor', white_text, ...
                    'ButtonPushedFcn', {@mean_apply});
    
                % Info button (adaptation needed for image handling)
               
               setappdata(0,'cutoff',cutoff)
            function mean_apply(varargin)

             cutoff=   getappdata(0,'cutoff');
             
            % matrices=getappdata(0,'myData');
            denoised=medfilt3(matrices.Images, [cutoff.Value cutoff.Value  1]);
            denoised(isnan(denoised))=0;
%             display_waitbar(1,1,90,100, waitbarHandle);
            
            matrices.Images=im2double(denoised);

            I=matrices.Images(:,:,2);
            %axes(app.image_axes)
            imshow(I,[],'Parent', app2.image_axes);
            colormap(app2.image_axes,getappdata(0,'color'));
            b=length(matrices.Wavelengths);
            app2.BandSlider.Limits =[1 b];
            setappdata(0,'I',I) 
            colormap(jet)
            setappdata(0,'myData',matrices);

            end
         
        end

        % Menu selected function: SaveasidcubeformatMenu
        function SaveasidcubeformatMenuSelected(app, event)
            % idcubeoneHandle=getappdata(0,'idcubeoneHandle');
    matrices=getappdata(0,'myData');
    % 
    % N2=get(idcubeoneHandle.tabAnalysisMathematics,'value');
    % N3 = get(idcubeoneHandle.tabAnalysisExpression,'value'); % For mathematical expression
    % N4 = get(idcubeoneHandle.radioButtonRGBMode,'value');
    % N5 = get(idcubeoneHandle.tabAnalysisBroadBand,'value');
    % if   N2==1 || N3==1 
    %     matrices=getappdata(0,'myData_save');
    % end
     
    Image= matrices.Images;
%     figure;
%     imshow(Image(:,:,15))
%     colormap;
    wavelength = matrices.Wavelengths;
    %loaded = getappdata(0,'folders');
    pathstr = getappdata(0,'default_directory');
    filename = getappdata(0,'file_name');
%         [pathstr,filename] = fileparts(loaded{1});
    pathname = uigetdir(pathstr,'Select Destination Folder'); % Select destination folder [pathstr '/' filename]
%         foldername=[filename ' - PCA'];
    newfileName = [filename(1:end-4) '-data'  '.mat'];
    full_path = fullfile(pathname, newfileName);
%         mkdir([pathname '/' foldername]);
    im_obj = whos('Image');wave_obj = whos('wavelength');
    hsidata_size = (im_obj.bytes+wave_obj.bytes)/(1024^3);

    if hsidata_size>2
        save(full_path, 'Image', 'wavelength','-v7.3');
    else
        save(full_path, 'Image', 'wavelength');
    end
%     counter = counter + 1; setappdata(0,'mnfpcaExportCounter', counter);
%         save([pathname '/' foldername '/wavelength.mat'],'wavelength');
%         save([pathname '/' foldername '/Image.mat'],'Image','-v7.3'); %image file autosaves together


%% Change figure title icon
% folder = fileparts(mfilename('fullpath'));
% iconFilePath = fullfile(folder, 'Images', 'idcube-icon-transparent.png');
% setIcon(mnfH.figure, iconFilePath);

%msgbox(' datacube saved to directory')
folder = fileparts(mfilename('fullpath'));
iconFilePath = fullfile(folder, 'Images', 'idcube-icon-transparent.png');
setIcon(gcf, iconFilePath);
waitfor(setIcon(msgbox('Datacube Saved to Directory', 'Saving Datacube', 'help')))


%     catch
%         return
%     end
    
        end

        % Menu selected function: SaveasimageMenu
        function SaveasimageMenuSelected(app, event)
           
    [filename, pathname] = uiputfile({'*.png';'*.jpg';'*.tif'},'Save Image As...');
    temp = figure('visible','off');

%%
% Create a new figure (not visible)
temp = figure('visible', 'off');

% Get the image data from the original axes
temp_img = findall(app.image_axes, 'type', 'Image');
img_data = get(temp_img, 'CData');

% Create new axes in the temporary figure and plot the image data
axes_temp = axes('Parent', temp);
imshow(img_data, 'Parent', axes_temp);

% Remove ticks and labels
set(axes_temp, 'xtick', [], 'ytick', [], 'xticklabel', [], 'yticklabel', []);

% Set figure size to match the image size
truesize(temp, [size(img_data, 1) + 8, size(img_data, 2)]);

% Save the figure as an image
saveas(temp, fullfile(pathname, filename));


%%

%     return
% end
folder = fileparts(mfilename('fullpath'));
iconFilePath = fullfile(folder, 'Images', 'idcube-icon-transparent.png');
setIcon(gcf, iconFilePath);
(setIcon(msgbox({'The Image file has not been saved'}, 'Saving Image','help')));
        end

        % Menu selected function: PCAMenu
        function PCAMenuSelected(app, event)
        app.TabGroup.Visible='on'  ;
        app.TabGroup.SelectedTab=app.PCATab;
        matrices = getappdata(0, 'myData');
        Images = matrices.Images;
        % set(app.ThreeD,'Visible','on')
        % set(app.image_axes,'Visible','off')
        % set(app.BacktooriginalButton,'Visible','on')
        num=app.NumberofPCAEditField.Value;
        
        % set(gca, 'color', bgc);
        % set(gca, 'XColor', 'none', 'YColor', 'none', 'ZColor', 'none');
        [outputDataCube,coeff,var] = hyperpca(Images,num,'Method','Eig');
        I=outputDataCube(:,:,2);
        %axes(app.image_axes)
        imshow(I,[],'Parent', app.axes_PCA);
        colormap(app.axes_PCA,getappdata(0,'color'));
        [~,~,b]=size(outputDataCube);
        app.Slider_PCA.Limits =[1 b];
        setappdata(0,'I',I)
        colormap(jet)
        setappdata(0,'myDataPCA',outputDataCube)
        app.BandSlider.Visible='off';
        set(app.BacktooriginalButton_2,'Visible','on')
        % h = load('trans0.mat');
        % y = struct2cell(h);
        % 
        % hsidata = y{1};
        % y = struct2cell(hsidata);
        % alphamap = y{8};
        % 
        % 
        % 
        % colormap2 = gray;
        % 
        % opengl hardware;
        % 
        % V = volshow(Images, 'Parent', app.ThreeD, 'Colormap', colormap2, 'Alphamap', alphamap);
        % 
        % 
        % VAxis = [0.2, 1.5];
        % setappdata(0, 'V', V);
        % % camtarget;
        % newscale = linspace(min(Images(:)) - min(VAxis(:)), ...
        %     max(Images(:)) - min(VAxis(:)), size(colormap2, 1)) / diff(VAxis);
        % newscale(newscale < 0) = 0;
        % newscale(newscale > 1) = 1;
        % V.Colormap = interp1(linspace(0, 1, size(colormap2, 1)), colormap2, newscale);
               % threeD_plot8(1)
   

        end

        % Button pushed function: BacktooriginalButton, 
        % ...and 1 other component
        function BacktooriginalButtonPushed(app, event)
            matrices=getappdata(0,'myData');
            Image=im2double(matrices.Images);
            % matrices.Wavelengths = wavelength;
                    % display_waitbar(1,1,70,100, waitbarHandle);

            % set(app.ThreeD,'Visible','off')
            set(app.image_axes,'Visible','on')      
            set(app.BacktooriginalButton,'Visible','off')
                    %%

            I=Image(:,:,2);
            %axes(app.image_axes)
            imshow(I,[],'Parent', app.image_axes);
            colormap(app.image_axes,getappdata(0,'color'));
            b=length(matrices.Wavelengths);
            app.BandSlider.Limits =[1 b];
            app.TabGroup.Visible='off';
            app.BandSlider.Visible='on';
            colormap(jet)
        end

        % Callback function: not associated with a component
        function BandSliderValueChanged2(app, event)
           

            value = round(app.BandSlider.Value);

               matrices=getappdata(0,'myData');
             Image = im2double(matrices.Images );
                    % display_waitbar(1,1,70,100, waitbarHandle);
             I=Image(:,:,value);
            axes(app.image_axes)
            imshow(I,[],'Parent', app.image_axes,'Colormap',jet);
            
        end

        % Callback function: not associated with a component
        function InteractiveTurnonButtonPushed2(app, event)
            app.UIFigure.WindowButtonMotionFcn = @(src, event) app.mouse_move();
        end

        % Callback function: not associated with a component
        function BacktooriginalButtonPushed2(app, event)
            matrices=getappdata(0,'myData');
            Image=im2double(matrices.Images);
            wavelength=matrices.Wavelengths ;
                    % display_waitbar(1,1,70,100, waitbarHandle);

            set(app.ThreeD,'Visible','off')
            set(app.image_axes,'Visible','on')      
            set(app.BacktooriginalButton,'Visible','off')
                    %%

            I=Image(:,:,2);
            %axes(app.image_axes)
            imshow(I,[],'Parent', app.image_axes);
            % colormap(app.image_axes,getappdata(0,'color'));
            b=length(matrices.Wavelengths);
            app.BandSlider.Limits =[1 b];
         
            % colormap(jet)
        end

        % Callback function: not associated with a component
        function PCATabButtonDown(app, event)
            app.PCATab='Visible';
            PCAMenuSelected(app,event)  %Callback of PCA


        end

        % Value changed function: NumberofPCAEditField
        function NumberofPCAEditFieldValueChanged(app, event)
            value = app.NumberofPCAEditField.Value;
             PCAMenuSelected(app,event) %Callback of PCA
        end

        % Value changed function: Slider_PCA
        function Slider_PCAValueChanged(app, event)
          
         val=app.Slider_PCA.Value;
        % set(gca, 'color', bgc);
        % set(gca, 'XColor', 'none', 'YColor', 'none', 'ZColor', 'none');
        outputDataCube=  getappdata(0,'myDataPCA');
        I=outputDataCube(:,:,round(val));
        %axes(app.image_axes)
        imshow(I,[],'Parent', app.axes_PCA);
        colormap(app.axes_PCA,getappdata(0,'color'));
        [~,~,b]=size(outputDataCube);
        app2.BandSlider.Limits =[1 b];
        setappdata(0,'I',I)
        colormap(jet)
        setappdata(0,'myDataPCA',outputDataCube)
        % h = load('trans0.mat');
        end

        % Value changed function: BandSlider
        function BandSliderValueChanged3(app, event)
            value = app.BandSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.Text = 'Home';
            app.OpenfileMenu = uimenu(app.HomeMenu);
            app.OpenfileMenu.Text = 'Open file';
            app.matMenu = uimenu(app.OpenfileMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenfileMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.h5Menu = uimenu(app.OpenfileMenu);
            app.h5Menu.MenuSelectedFcn = createCallbackFcn(app, @h5MenuSelected, true);
            app.h5Menu.Text = '.h5';
            app.niMenu = uimenu(app.OpenfileMenu);
            app.niMenu.MenuSelectedFcn = createCallbackFcn(app, @niMenuSelected, true);
            app.niMenu.Text = '.ni';
            app.FIlterMenu = uimenu(app.UIFigure);
            app.FIlterMenu.Text = 'FIlter';
            app.MeanMenu = uimenu(app.FIlterMenu);
            app.MeanMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanMenuSelected, true);
            app.MeanMenu.Text = 'Mean';
            app.ToolboxesMenu = uimenu(app.UIFigure);
            app.ToolboxesMenu.Text = 'Toolboxes';
            app.PCAMenu = uimenu(app.ToolboxesMenu);
            app.PCAMenu.MenuSelectedFcn = createCallbackFcn(app, @PCAMenuSelected, true);
            app.PCAMenu.Text = 'PCA';
            app.spectra = uiaxes(app.UIFigure);
            title(app.spectra, 'Spectra')
            xlabel(app.spectra, 'Wavelength/Band')
            ylabel(app.spectra, 'Reflectance')
            zlabel(app.spectra, 'Z')
            app.spectra.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.spectra.XTickLabel = '';
            app.spectra.YTickLabel = '';
            app.image_axes = uiaxes(app.UIFigure);
            title(app.image_axes, 'Image')
            zlabel(app.image_axes, 'Z')
            app.image_axes.XTick = [];
            app.image_axes.XTickLabel = '';
            app.image_axes.YTick = [];
            app.image_axes.YTickLabel = '';
            app.BacktooriginalButton = uibutton(app.UIFigure, 'push');
            app.BacktooriginalButton.ButtonPushedFcn = createCallbackFcn(app, @BacktooriginalButtonPushed, true);
            app.BacktooriginalButton.Visible = 'off';
            app.BacktooriginalButton.Text = 'Back to original';
            app.InteractiveTurnonButton = uibutton(app.UIFigure, 'push');
            app.InteractiveTurnonButton.ButtonPushedFcn = createCallbackFcn(app, @InteractiveTurnonButtonPushed, true);
            app.InteractiveTurnonButton.Icon = fullfile(pathToMLAPP, 'icons', '_IDCube-icon-Machine Learning-1.jpg');
            app.InteractiveTurnonButton.Text = 'Interactive Turn on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.axes_PCA = uiaxes(app.PCATab);
            title(app.axes_PCA, 'PCA-Band')
            zlabel(app.axes_PCA, 'Z')
            app.axes_PCA.XTick = [];
            app.axes_PCA.YTick = [];
            app.NumberofPCAEditFieldLabel = uilabel(app.PCATab);
            app.NumberofPCAEditFieldLabel.Text = 'Number of PCA';
            app.NumberofPCAEditField = uieditfield(app.PCATab, 'numeric');
            app.NumberofPCAEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPCAEditFieldValueChanged, true);
            app.NumberofPCAEditField.Value = 5;
            app.Slider_PCA = uislider(app.PCATab);
            app.Slider_PCA.ValueChangedFcn = createCallbackFcn(app, @Slider_PCAValueChanged, true);
            app.Slider_PCA.Value = 1;
            app.SliderLabel = uilabel(app.PCATab);
            app.SliderLabel.Text = 'Slider';
            app.BacktooriginalButton_2 = uibutton(app.PCATab, 'push');
            app.BacktooriginalButton_2.ButtonPushedFcn = createCallbackFcn(app, @BacktooriginalButtonPushed, true);
            app.BacktooriginalButton_2.Visible = 'off';
            app.BacktooriginalButton_2.Text = 'Back to original';
            app.BandSliderLabel = uilabel(app.UIFigure);
            app.BandSliderLabel.Text = 'Band';
            app.BandSlider = uislider(app.UIFigure);
            app.BandSlider.ValueChangedFcn = createCallbackFcn(app, @BandSliderValueChanged3, true);
            app.BandSlider.ValueChangingFcn = createCallbackFcn(app, @BandSliderValueChanged, true);
            app.BandSlider.Value = 1;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SaveasidcubeformatMenu = uimenu(app.ContextMenu);
            app.SaveasidcubeformatMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasidcubeformatMenuSelected, true);
            app.SaveasidcubeformatMenu.Text = 'Save as idcube format';
            app.SaveasimageMenu = uimenu(app.ContextMenu);
            app.SaveasimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasimageMenuSelected, true);
            app.SaveasimageMenu.Text = 'Save as image';
            % Assign app.ContextMenu
            app.image_axes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = web_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end