classdef web_app_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IDCubeProUIFigure              matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        OKButton_2                     matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        MultiSpectraModeButton         matlab.ui.control.Button
        DataTextArea                   matlab.ui.control.TextArea
        CancelButton                   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ExtractSpectrumButton          matlab.ui.control.Button
        GetDataButton                  matlab.ui.control.Button
        ColormapSelectionButtonGroup   matlab.ui.container.ButtonGroup
        prismButton                    matlab.ui.control.RadioButton
        linesButton                    matlab.ui.control.RadioButton
        colorcubeButton                matlab.ui.control.RadioButton
        hsvButton                      matlab.ui.control.RadioButton
        winterButton                   matlab.ui.control.RadioButton
        summerButton                   matlab.ui.control.RadioButton
        springButton                   matlab.ui.control.RadioButton
        pinkButton                     matlab.ui.control.RadioButton
        hotButton                      matlab.ui.control.RadioButton
        grayButton                     matlab.ui.control.RadioButton
        copperButton                   matlab.ui.control.RadioButton
        coolButton                     matlab.ui.control.RadioButton
        boneButton                     matlab.ui.control.RadioButton
        flagButton                     matlab.ui.control.RadioButton
        autumnButton                   matlab.ui.control.RadioButton
        jetButton                      matlab.ui.control.RadioButton
        ChannelSelectionButtonGroup    matlab.ui.container.ButtonGroup
        RGBButton                      matlab.ui.control.RadioButton
        SingleChannelButton            matlab.ui.control.RadioButton
        Band3Slider                    matlab.ui.control.Slider
        Band3SliderLabel               matlab.ui.control.Label
        Band2Slider                    matlab.ui.control.Slider
        Band2SliderLabel               matlab.ui.control.Label
        Band1Slider                    matlab.ui.control.Slider
        Band1SliderLabel               matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        OPenFileTypeDropDown           matlab.ui.control.DropDown
        OPenFileTypeDropDownLabel      matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        BandSlider                     matlab.ui.control.Slider
        BandSliderLabel                matlab.ui.control.Label
        InteractiveTurnonButton        matlab.ui.control.Button
        spectra                        matlab.ui.control.UIAxes
        image_axes                     matlab.ui.control.UIAxes
        PCATab                         matlab.ui.container.Tab
        Slider_PCA_3                   matlab.ui.control.Slider
        Slider3Label                   matlab.ui.control.Label
        Slider_PCA_2                   matlab.ui.control.Slider
        Slider2Label                   matlab.ui.control.Label
        Slider_PCA_1                   matlab.ui.control.Slider
        Slider1Label                   matlab.ui.control.Label
        ChannelSelectionButtonGroup_2  matlab.ui.container.ButtonGroup
        RGBButton_2                    matlab.ui.control.RadioButton
        SingleChannelButton_2          matlab.ui.control.RadioButton
        PCALabel                       matlab.ui.control.Label
        BacktooriginalButton_2         matlab.ui.control.Button
        SliderLabel                    matlab.ui.control.Label
        Slider_PCA                     matlab.ui.control.Slider
        NumberofPCAEditField           matlab.ui.control.NumericEditField
        NumberofPCAEditFieldLabel      matlab.ui.control.Label
        axes_PCA                       matlab.ui.control.UIAxes
        FilterTab                      matlab.ui.container.Tab
        BandSlider_2                   matlab.ui.control.Slider
        BandSlider_2Label              matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        RemovefilterButton             matlab.ui.control.Button
        FilterSizeEditField            matlab.ui.control.NumericEditField
        FilterSizeEditFieldLabel       matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        SigmaEditFieldLabel            matlab.ui.control.Label
        FiltergroupButtonGroup         matlab.ui.container.ButtonGroup
        DgaussianButton                matlab.ui.control.RadioButton
        MeanSpectralButton             matlab.ui.control.RadioButton
        MeanSpatialButton              matlab.ui.control.RadioButton
        ApplyFilterButton              matlab.ui.control.Button
        CutoffEditField                matlab.ui.control.NumericEditField
        CutoffEditFieldLabel           matlab.ui.control.Label
        axes_Filter                    matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        SaveasidcubeformatMenu         matlab.ui.container.Menu
        SaveasimageMenu                matlab.ui.container.Menu
    end

    
    % methods (Access = private)
    % 
    %     function results = mouse_move(app)
    % 
    %         C = get(app.image_axes,'currentpoint');
    %         x = round(C(1,1));
    %         y = round(C(1,2));
    % 
    %         xlim = get(  app.image_axes,'xlim');
    %         ylim = get(  app.image_axes,'ylim');
    %         outX = ~any(diff([xlim(1) C(1,1) xlim(2)])<0);
    %         outY = ~any(diff([ylim(1) C(1,2) ylim(2)])<0);
    %         if outX&&outY
    % 
    %             val_channel = reshape(mat(y,x,:),[1,dim(end)]);
    %             [I,b] = max(val_channel) ;
    %             [val,idx] = max(y) ;
    %             C = [x(idx) y(idx)] ;
    %             plot(x, y, '-o','MarkerSize',50/(NumberChannels+3),'LineWidth',1.5,'color', [0.0244, 0.435, 0.8755],'Parent', app.spectra);
    %             hold on
    % 
    %             plot(x(idx), y(idx),'o','MarkerSize',10,'LineWidth',1.0,'Parent', app.spectra)
    %             xlab='Band/Channel'; 
    %             xlabel(xlab, 'Color', fgc);
    %             ylabel('Intensity', 'Color', fgc);
    %         end
    %     end
    % end
   properties (Access = public)
        CurrentAxes % Description
        tabTitleStr
   end

   properties (Access = private)
        MultiSpectraActive = false;     % Flag for multi-spectra mode
        MultiSpectraStartPoint;         % For drawing rectangle [x,y] on image_axes
        MultiSpectraTempRectHandle;     % Handle to the temporary rectangle being drawn on image_axes
        
        MultiSpectraPlotHandles = gobjects(0); % Handles to plots on app.spectra (lines and fills)
        MultiSpectraLegendStrings = {}; % Legend strings
        MultiSpectraCount = 0;          % Number of spectra plotted
        MultiSpectraMax = 25;           % Max number of spectra for colormap
        MultiSpectraCMap;               % Colormap for spectra (e.g., lines(25))
        MultiSpectraROIMarkers = gobjects(0); % Handles to persistent ROIs on image_axes
        MultiSpectraROIMarkerTextHandles = gobjects(0); % Handles to text labels on image_axes %

        
   end
        
    methods (Access = private)
   
        function displaySpectrumFromPoint(app, x, y)
            matrices = getappdata(0, 'myData');
            if isempty(matrices)
                return;
            end
            if isempty(matrices) || ~isfield(matrices, 'Images') || ~isfield(matrices, 'Wavelengths')
                uialert(app.IDCubeProUIFigure, 'Image or wavelength data not found.', 'Data Error', 'Icon', 'error');
                return;
            end
            mat = matrices.Images;
            wavelengths = matrices.Wavelengths;
            
            dim = size(mat);
            % Ensure x and y are within valid image dimensions before accessing mat
            if y > 0 && y <= dim(1) && x > 0 && x <= dim(2)
                val_channel = squeeze(mat(y, x, :)); % Squeeze to get a vector
                % Ensure val_channel is a column vector for plotting
                if size(val_channel,2) > 1 
                    val_channel = val_channel';
                end
                app.updateSpectrumPlotCore(wavelengths, val_channel);
            else
                % This case should ideally be caught by initial validation in OKButtonPushed
                uialert(app.IDCubeProUIFigure, 'Coordinates for spectrum point are out of bounds.', 'Error', 'Icon', 'error');
            end
        end

        function displaySpectrumFromROI(app, x, y, roi_width, roi_height)
            matrices = getappdata(0, 'myData');
            if isempty(matrices)
                return;
            end
            mat = matrices.Images;
            wavelengths = matrices.Wavelengths;

            dim = size(mat);
            % Ensure ROI is within valid image dimensions
            if y > 0 && (y + roi_height - 1) <= dim(1) && x > 0 && (x + roi_width - 1) <= dim(2)
                roi_data = mat(y:(y + roi_height - 1), x:(x + roi_width - 1), :);
                avg_spectrum = squeeze(mean(mean(roi_data, 1), 2)); % Squeeze to get a vector
                % Ensure avg_spectrum is a column vector for plotting
                if size(avg_spectrum,2) > 1
                    avg_spectrum = avg_spectrum';
                end
                app.updateSpectrumPlotCore(wavelengths, avg_spectrum);
            else
                % This case should ideally be caught by initial validation in OKButtonPushed
                uialert(app.IDCubeProUIFigure, 'ROI dimensions for spectrum are out of bounds.', 'Error', 'Icon', 'error');
            end
        end

        function updateSpectrumPlotCore(app, xData, yData)
            % Ensure xData and yData are correctly oriented for plot (e.g., column vectors)
            if isrow(xData)
                xData = xData';
            end
            if isrow(yData)
                yData = yData';
            end
            
            % Check if xData and yData have compatible sizes
            if length(xData) ~= length(yData)
                uialert(app.IDCubeProUIFigure, 'Wavelength and intensity data have mismatched lengths.', 'Plotting Error', 'Icon', 'error');
                return;
            end

            plot(app.spectra, xData, yData, 'LineWidth', 1.5, 'Color', [0.0244, 0.435, 0.8755]);
            % Resetting limits can be useful if spectrum scale changes drastically
            % xlim(app.spectra, 'auto');
            % ylim(app.spectra, 'auto');
        end

        function enableInteractions(app)
             if(~isempty(app.CurrentAxes))
                 app.PanSwitch.Enable = 'on';
                 app.ZoomSwitch.Enable = 'on';
                 app.RotateSwitch.Enable = 'on';
             end
        end
        
        function mouse_move(app)

          % app=getappdata(0,'idcube');

            C = get(app.image_axes,'currentpoint');
            x = round(C(1,1));
            y = round(C(1,2));
            matrices=getappdata(0,'myData');
            if isempty(matrices)
                return;
            end
            mat = matrices.Images;
            wavelengths=matrices.Wavelengths;
            NumberChannels = numel(wavelengths);
%         mat = getappdata(0,'Image');
            dim = size(mat);
            xlim = get(  app.image_axes,'xlim');
            ylim = get(  app.image_axes,'ylim');
            outX = ~any(diff([xlim(1) C(1,1) xlim(2)])<0);
            outY = ~any(diff([ylim(1) C(1,2) ylim(2)])<0);
            if outX&&outY
             try
                val_channel = reshape(mat(y,x,:),[1,dim(end)]);
                [I,b] = max(val_channel) ;
                new_x=wavelengths(1,:);
                [val,idx] = max(val_channel) ;
                % C = [x(idx) y(idx)] ;
                plot(new_x, val_channel, '-o','MarkerSize',50/(NumberChannels+3),'LineWidth',1.5,'color', [0.0244, 0.435, 0.8755],'Parent', app.spectra);
                % hold on

                % plot(new_x(idx), val_channel(idx),'o','MarkerSize',10,'LineWidth',1.0)
                xlab='Band/Channel'; 
                % xlabel(xlab);
                % ylabel('Intensity');
                % grid on
                % hold off
             catch
             end
            end
            
        end
        function freeze_spectrum_on_click(app) 
            setptr(app.IDCubeProUIFigure, 'arrow');
            app.IDCubeProUIFigure.WindowButtonMotionFcn = '';
            app.IDCubeProUIFigure.WindowButtonDownFcn = '';
            app.InteractiveTurnonButton.Enable = "on";
        end
        function tabVisibility(app,targetState,targetTab)
            if targetState == 0
                app.(targetTab).Parent = [];
            else
                app.(targetTab).Parent = app.TabGroup;
            end
        end

        function clearMultiSpectraElements(app)
            % Clear plots from spectra axes
            cla(app.spectra);
            legend(app.spectra, 'off'); % Turn off legend
            title(app.spectra, '\color{white}Spectra'); % Reset title
            xlabel(app.spectra, 'Wavelength/Band');
            ylabel(app.spectra, 'Reflectance');

            single_extract_markers = findobj(app.image_axes.Children, 'Tag', 'ROIMarker');
            if ~isempty(single_extract_markers) && all(isvalid(single_extract_markers))
                delete(single_extract_markers);
            end

            % Delete ROIs from image axes
            delete(app.MultiSpectraROIMarkers(isvalid(app.MultiSpectraROIMarkers)));
            app.MultiSpectraROIMarkers = gobjects(0);

            delete(app.MultiSpectraROIMarkerTextHandles(isvalid(app.MultiSpectraROIMarkerTextHandles)));
            app.MultiSpectraROIMarkerTextHandles = gobjects(0);

            % Reset internal tracking
            app.MultiSpectraPlotHandles = gobjects(0);
            app.MultiSpectraLegendStrings = {};
            app.MultiSpectraCount = 0;
        end

        function handleMultiSpectraButtonDown(app, ~, event)
            if ~app.MultiSpectraActive; return; end

            % Check if click is within image_axes
            ax_pos = getpixelposition(app.image_axes);
            fig_cp = get(app.IDCubeProUIFigure, 'CurrentPoint');
            
            isOverAxes = fig_cp(1) >= ax_pos(1) && fig_cp(1) <= ax_pos(1)+ax_pos(3) && ...
                           fig_cp(2) >= ax_pos(2) && fig_cp(2) <= ax_pos(2)+ax_pos(4);

            if ~isOverAxes; return; end

            cp_axes = app.image_axes.CurrentPoint(1,1:2);
            app.MultiSpectraStartPoint = cp_axes;

            % Delete previous temporary rectangle if it exists and is valid
            if ~isempty(app.MultiSpectraTempRectHandle) && isvalid(app.MultiSpectraTempRectHandle)
                delete(app.MultiSpectraTempRectHandle);
            end
            
            app.MultiSpectraTempRectHandle = rectangle(app.image_axes, ...
                'Position', [cp_axes(1), cp_axes(2), 1, 1], ... % Start with a tiny rectangle
                'EdgeColor', 'r', 'LineStyle', '--', 'Tag', 'MultiROIMarker_temp');
            
            app.IDCubeProUIFigure.WindowButtonMotionFcn = @app.handleMultiSpectraButtonMotion;
            app.IDCubeProUIFigure.WindowButtonUpFcn = @app.handleMultiSpectraButtonUp;
        end

        function handleMultiSpectraButtonMotion(app, ~, ~)
            if ~app.MultiSpectraActive || isempty(app.MultiSpectraStartPoint) || ...
               isempty(app.MultiSpectraTempRectHandle) || ~isvalid(app.MultiSpectraTempRectHandle)
                return; 
            end

            cp_axes = app.image_axes.CurrentPoint(1,1:2);
            
            x_start = app.MultiSpectraStartPoint(1);
            y_start = app.MultiSpectraStartPoint(2);
            x_curr = cp_axes(1);
            y_curr = cp_axes(2);

            pos(1) = min(x_start, x_curr);
            pos(2) = min(y_start, y_curr);
            pos(3) = abs(x_start - x_curr);
            pos(4) = abs(y_start - y_curr);
            
            % Ensure width and height are at least 1 for visibility
            if pos(3) < 1; pos(3) = 1; end
            if pos(4) < 1; pos(4) = 1; end

            app.MultiSpectraTempRectHandle.Position = pos;
        end

        function handleMultiSpectraButtonUp(app, ~, ~)
            if ~app.MultiSpectraActive || isempty(app.MultiSpectraStartPoint) || ...
               isempty(app.MultiSpectraTempRectHandle) || ~isvalid(app.MultiSpectraTempRectHandle)
                app.IDCubeProUIFigure.WindowButtonMotionFcn = ''; % Clear motion even if error
                app.IDCubeProUIFigure.WindowButtonUpFcn = '';   % Clear up even if error
                return; 
            end

            finalPos = app.MultiSpectraTempRectHandle.Position;
            delete(app.MultiSpectraTempRectHandle);
            app.MultiSpectraTempRectHandle = []; % Clear the handle

            % Reset motion and up functions
            app.IDCubeProUIFigure.WindowButtonMotionFcn = '';
            app.IDCubeProUIFigure.WindowButtonUpFcn = '';
            
            % Ensure ROI has some area
            if finalPos(3) < 1 || finalPos(4) < 1
                % uialert(app.IDCubeProUIFigure, 'ROI too small.', 'Info', 'Icon', 'info');
                return; % Or treat as a point if desired
            end

            app.MultiSpectraCount = app.MultiSpectraCount + 1;
            if app.MultiSpectraCount > app.MultiSpectraMax
                uialert(app.IDCubeProUIFigure, ['Max ' num2str(app.MultiSpectraMax) ' spectra reached.'], 'Limit Exceeded', 'Icon', 'warning');
                app.MultiSpectraCount = app.MultiSpectraCount - 1; % Decrement back
                return;
            end

            matrices = getappdata(0, 'myData');
            if isempty(matrices)
                return;
            end
            
            img_data = matrices.Images;
            wavelengths = matrices.Wavelengths;
            [img_rows, img_cols, ~] = size(img_data);

            % Convert ROI from axes coordinates to image pixel indices
            x_roi = round(finalPos(1));
            y_roi = round(finalPos(2));
            w_roi = round(finalPos(3));
            h_roi = round(finalPos(4));

            % Clamp ROI to image boundaries
            x_roi_end = min(img_cols, x_roi + w_roi - 1);
            y_roi_end = min(img_rows, y_roi + h_roi - 1);
            x_roi = max(1, x_roi);
            y_roi = max(1, y_roi);
            
            w_roi_clamped = x_roi_end - x_roi + 1;
            h_roi_clamped = y_roi_end - y_roi + 1;

            if w_roi_clamped < 1 || h_roi_clamped < 1
                % uialert(app.IDCubeProUIFigure, 'ROI outside image or too small after clamping.', 'Info', 'Icon', 'info');
                app.MultiSpectraCount = app.MultiSpectraCount - 1; % Decrement back
                return;
            end
            
            % Draw persistent ROI marker on image
            roiMarkerColor = app.MultiSpectraCMap(app.MultiSpectraCount, :);
            newROIMarker = rectangle(app.image_axes, 'Position', [x_roi, y_roi, w_roi_clamped, h_roi_clamped], ...
                                     'EdgeColor', roiMarkerColor, 'LineWidth', 1.5, 'Tag', 'MultiROIMarker');
            app.MultiSpectraROIMarkers(end+1) = newROIMarker;
            
            % Draw persistent text marker
            spectrumName = ['S - ' num2str(app.MultiSpectraCount)];
            % Assuming (x_roi, y_roi) is the top-left corner due to imshow's reversed Y-axis
            labelText = sprintf('%s\n(%d,%d,%d,%d)', spectrumName, x_roi, y_roi, w_roi_clamped, h_roi_clamped);
            
            % Position the text slightly above the ROI's top-left corner
            % (y_roi - 5 means 5 pixels "up" in image coordinates)
            text_x_position = x_roi;
            text_y_position = y_roi; % Adjust offset as needed

            newROIMarkerText = text(app.image_axes, text_x_position, text_y_position, labelText, ...
                                    'Color', roiMarkerColor, ... % Use ROI color for association
                                    'FontSize', 9, ...           % Adjust font size as needed
                                    'VerticalAlignment', 'bottom', ... % Text anchor is at its bottom-left
                                    'HorizontalAlignment', 'left', ...
                                    'Tag', 'MultiROIMarker_Text', ...
                                    'Interpreter', 'none'); % To ensure underscores in name are literal
            app.MultiSpectraROIMarkerTextHandles(end+1) = newROIMarkerText;


            % Extract spectral data from the clamped ROI
            roi_pixel_data = img_data(y_roi:y_roi_end, x_roi:x_roi_end, :);
            
            [n_roi_rows, n_roi_cols, num_bands] = size(roi_pixel_data);
            % Reshape so each row is a pixel's spectrum: (num_pixels_in_roi, num_bands)
            spectral_data_for_plot = reshape(permute(roi_pixel_data, [1 2 3]), [n_roi_rows*n_roi_cols, num_bands]);

            % Plot mean +- std
            app.plotMeanStdOnSpectraAxes(wavelengths, spectral_data_for_plot, roiMarkerColor, 0.3);
            
            app.MultiSpectraLegendStrings{end+1} = ['S - ' num2str(app.MultiSpectraCount)];
            legend(app.spectra, app.MultiSpectraLegendStrings, 'TextColor', 'white', 'Location', 'best', 'AutoUpdate', 'off');
        end

        function plotMeanStdOnSpectraAxes(app, wavelengths, spectral_data, color, alpha_val)
            if isempty(spectral_data)
                return;
            end
            mean_spec = mean(spectral_data, 1, 'omitnan');
            std_spec = std(spectral_data, 0, 1, 'omitnan');
            
            if iscolumn(wavelengths)
                wavelengths_row = wavelengths';
            else
                wavelengths_row = wavelengths;
            end

            hold(app.spectra, 'on');
            
            upper_bound = mean_spec + std_spec;
            lower_bound = mean_spec - std_spec;
            
            valid_data = ~isnan(mean_spec) & ~isnan(std_spec);
            if ~any(valid_data); hold(app.spectra, 'off'); return; end 

            hFill = fill(app.spectra, ...
                 [wavelengths_row(valid_data) fliplr(wavelengths_row(valid_data))], ...
                 [upper_bound(valid_data) fliplr(lower_bound(valid_data))], ...
                 color, 'FaceAlpha', alpha_val, 'EdgeColor', color, 'LineWidth', 0.5); % EdgeColor can be same as fill or slightly darker
            
            hold(app.spectra, 'off');
            
            app.MultiSpectraPlotHandles(end+1) = hFill; % Only store handle to the fill object
            
            xlim(app.spectra, [min(wavelengths_row) max(wavelengths_row)]);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OPenFileTypeDropDown
        function OpenfileMenuSelected(app, event)
           app.CurrentAxes = app.image_axes;
         if app.OPenFileTypeDropDown.Value=='.mat'
        app.ChannelSelectionButtonGroup.SelectedObject = app.SingleChannelButton;
            [fileName,pathName,indx] = uigetfile({fullfile(pwd,'*.mat')},'Select .mat file','MultiSelect','on');
            path_filename = fullfile(pathName,fileName);
                 x = load(path_filename);
                            x_field_length = length(fieldnames(x));
                            % display_waitbar(1,1,60,100, waitbarHandle);
                            y = struct2cell(x);
                            if x_field_length == 1
                                hsidata = y{1};
                                if isstruct(hsidata)
                                    hsidata = struct2cell(hsidata);
                                end
                                if length(hsidata)==2
                                    Image = hsidata{1};
                                    wavelength = hsidata{2};
                                else
                                    if ndims(hsidata)==3
                                        Image = hsidata;
                                        wavelength = 1:size(Image,3);
                                    else
                                        showErrorAndExit('Image data are not found. Please check the file.');
                                        return
                                    end
                                end
                                elseif x_field_length == 2
                                hsidata = y;
                                if ndims(hsidata{1})==3
                                    Image = hsidata{1};
                                    wavelength = hsidata{2};
                                else
                                    Image = hsidata{2};
                                    wavelength = hsidata{1};
                                end
                            
                            end

            %%%Save data

                  
                    Image(isnan(Image))=0;
                    wavelength(isnan(wavelength))=0;

                    % Store data into matrices handle
                    matrices.Images = im2double(Image);
                    matrices.Wavelengths = wavelength;
                    % display_waitbar(1,1,70,100, waitbarHandle);

                    setappdata(0,'myData',matrices);
                    setappdata(0,'myDataOriginal',matrices);
                    idcube.axes=app.image_axes;
                    set(app.TextArea,'Value',fileName)
                    setappdata(0,'idcube',idcube)
                    %%

                    UpdateImage.updateImage(app)
                    % colormap(app.image_axes,getappdata(0,'color'));
                    lower=round(round(min(matrices.Wavelengths)));
                    upper=round(max(matrices.Wavelengths));
                    app.BandSlider.Limits =[lower upper];
                    app.Band1Slider.Limits =[lower upper];
                    app.Band2Slider.Limits =[lower upper];
                    app.Band3Slider.Limits =[lower upper];
                    filename=split(fileName,'.mat');
                    app.TabGroup.SelectedTab=app.HomeTab;
                    app.ColormapSelectionButtonGroup.Visible = "on";
                    app.BandSlider.Visible = "on";
                    app.Band1Slider.Visible = "off";
                    app.Band2Slider.Visible = "off";
                    app.Band3Slider.Visible = "off";
                    app.BandSliderLabel.Visible = "on";
                    app.Band1SliderLabel.Visible = "off";
                    app.Band2SliderLabel.Visible = "off";
                    app.Band3SliderLabel.Visible = "off";
                    setappdata(0,'file_name',filename{1})
                    % app.RemoveFilterButton.Visible='on';
         elseif app.OPenFileTypeDropDown.Value=='.h5'
                  h5MenuSelected(app,event);
         else
              niMenuSelected(app,event);
         end
        end

        % Value changed function: BandSlider
        function BandSliderValueChanged(app, event)
            UpdateImage.updateImage(app)
        end

        % Button pushed function: InteractiveTurnonButton
        function InteractiveTurnonButtonPushed(app, event)
                 app.clearMultiSpectraElements();
                 setptr(app.IDCubeProUIFigure, 'crosshair');
            app.IDCubeProUIFigure.WindowButtonMotionFcn = @(src, event) app.mouse_move();
                  % set(gcf,'WindowButtonMotionFcn', @mouse_move)
                 app.IDCubeProUIFigure.WindowButtonDownFcn = @(src, event) app.freeze_spectrum_on_click();
                 app.InteractiveTurnonButton.Enable = "off";

                 app.XEditField.Visible = "off";
                app.XEditFieldLabel.Visible = "off";
                app.YEditField.Visible = "off";
                app.YEditFieldLabel.Visible = "off";
                app.WidthEditField.Visible = "off";
                app.WidthEditFieldLabel.Visible = "off";
                app.HeightEditField.Visible = "off";
                app.HeightEditFieldLabel.Visible = "off";
                app.OKButton.Visible = "off";
                app.CancelButton.Visible = "off";
                app.ExtractSpectrumButton.Enable = "on";

                app.ClearButton.Visible = "off";
                app.OKButton_2.Visible = "off";
                app.MultiSpectraModeButton.Enable = "on";
        end

        % Callback function
        function h5MenuSelected(app, event)
            
            app.TabGroup.SelectedTab=app.HomeTab;
            [filename,pathname,findex] = uigetfile(fullfile(pwd,'*.h5*'),...
            'Select H5 data');
              % Retrieving information from the HDF5 file
              if ispc
                  fullfilename = [pathname,'\', filename];
              elseif ismac
                  fullfilename = [pathname,'/', filename];
              end
            info = hdf5info(fullfilename);
            T = struct2table(info);
            try
                data_image = info.GroupHierarchy.Datasets(1).Name;
                %     data_mask = info.GroupHierarchy.Datasets(2).Name;
                % Reading the image data from the HDF5 file

                image_image = hdf5read(fullfilename,data_image);
            catch

                data_image=info.GroupHierarchy.Groups.Groups.Datasets.Name; %(SWIR)
                data_image2=info.GroupHierarchy.Groups(1).Groups.Groups(1).Groups(1).Datasets(3).Name ; %VWIR
                image_image = hdf5read(fullfilename,data_image);
            end
            % Obtaining the names of the datasets from the HDF5 file
        %     data_image = info.GroupHierarchy.Datasets(1).Name;
        % %     data_mask = info.GroupHierarchy.Datasets(2).Name;
        % 
        %     % Reading the image data from the HDF5 file
        %     image_image = hdf5read(fullfilename,data_image);
            
            % Reordering the dimensions of the image data
            Image = permute(image_image,[3 2 1]);
            
            % Creating the XML file name with appropriate file separator based on the platform
            if ispc
                xmlfile = strcat(pathname,'\', filename,'.txt');
            elseif ismac
                xmlfile = strcat(pathname,'/', filename,'.txt');
            end
            
            % Opening the XML file for writing
            fid = fopen(xmlfile,'w');
            
            [r,c] = size(T);
            % Writing the contents of the HDF5 file's table to the XML file
            for i = 1:c
                try
                    col = T.Properties.VariableNames{i};
                    value = string(T{:,col});
                    p = strcat(col, ':', value);
                    fprintf(fid,'%s\n',p);
                catch
                end
            end
            fclose(fid);
            
            [r,c,b] = size(Image);
            wavelength = [1:b];
            %%%Save data

                    % 
                    % Image(isnan(Image))=0;
                    % wavelength(isnan(wavelength))=0;

                    % Store data into matrices handle
                    matrices.Images = im2double(Image);
                    matrices.Wavelengths = wavelength;
                    % display_waitbar(1,1,70,100, waitbarHandle);

                    setappdata(0,'myData',matrices);
                    setappdata(0,'myDataOriginal',matrices);
                    idcube.axes=app.image_axes;
                    set(app.TextArea,'Value',fileName)
                    setappdata(0,'idcube',idcube)
                    %%

            I=Image(:,:,2);
            %axes(app.image_axes)
            imshow(I,[],'Parent', app.image_axes,'Colormap',jet);
            colorbar(app.image_axes, "Color", [1 1 1])
            colormap(app.image_axes,getappdata(0,'color'));
            lower=round(min(matrices.Wavelengths));
            upper=round(max(matrices.Wavelengths));
            app.BandSlider.Limits =[lower upper];
            app.Band1Slider.Limits =[lower upper];
            app.Band2Slider.Limits =[lower upper];
            app.Band3Slider.Limits =[lower upper];
            filename=split(filename,'.h5');
            setappdata(0,'file_name',filename{1})
            % app.TabGroup.Visible='off';
            % app.BandSlider.Visible='on';
        end

        % Callback function
        function niMenuSelected(app, event)
           %% Nifti6y
           app.TabGroup.SelectedTab=app.HomeTab;
             [filename,pathname,findex] = uigetfile(fullfile(pwd,'*.nii*'),...
            'Select NII data',current_folder);
            dataname = [pathname filename];
%         [file_path, filename] = fileparts(dataname);
        if ispc
            fullfilename = [dataname,'\' ];
        elseif ismac
            fullfilename = [dataname,'/'];
        end          
        dicm2nii(fullfilename,fullfilename,'nii');
        k=getappdata(0,'nfi_file');
        k=[fullfilename,k{1}];
        Image=double(niftiread(strcat(k,'.nii')));
        
        
        [r,c,b]=size(Image);
        wavelength=[1:b];
%         Wavelength=Wavelength';
%         in = inputdlg_id(prompt,dlgtitle,dims);
%         try
%         in=str2double(in{1,1});
        % waitbarHandle = waitbar(0,'Please wait...','name','Converting DICOM format to IDCUBE format and saving...');
        % display_waitbar(1,1,10,100, waitbarHandle);
        pause(0.5);
        
%         Image=permute(reshape(Image, r, c/in, in), [3 2 1]);
        
        
        matrices.Images = Image;
        matrices.Wavelengths = wavelength;
        I=Image(:,:,2);
        setappdata(0,'I',I) 
            %axes(app.image_axes)
        imshow(I,[],'Parent', app.image_axes);
        colorbar(app.image_axes, "Color", [1 1 1])
        % colormap(app.image_axes,getappdata(0,'color'));
        lower=round(min(matrices.Wavelengths));
        upper=round(max(matrices.Wavelengths));
        app.BandSlider.Limits =[lower upper];
        app.Band1Slider.Limits =[lower upper];
        app.Band2Slider.Limits =[lower upper];
        app.Band3Slider.Limits =[lower upper];
        app.BandSlider.Visible='on';
        set(app.TextArea,'Value',fileName)
        colormap(app.image_axes,jet)
        setappdata(0,'file_name',filename{1})
        end

        % Button pushed function: ApplyFilterButton
        function MeanMenuSelected(app, event)
             
             if app.MeanSpatialButton.Value==1
                mean_apply()
             elseif app.MeanSpectralButton.Value==1
                 mean_spectral()
             else
                 gaussian()
             end
             
             % matrices=getappdata(0,'myData');
             % mean.fig = uifigure('Name', 'Spatial Filter Parameters',...
             %        'NumberTitle', 'off', ...
             %        'Position', [400 400 350 130], ... % Adjusted for absolute positioning in pixels
             %        'WindowStyle', 'modal', ...
             %        'Resize', 'off',...
             %        'AutoResizeChildren', 'off', 'Icon', 'idcube-icon-transparent.png');
             % 
             %    % Color definition for buttons
             %    green_btn = [77, 194, 115]/255;
             %    white_text = [1, 1, 1];  % White text color
             % 
             %    % Filter cutoff text label
             %    cutoff_text = uilabel(mean.fig, ...
             %        'Text', 'Filter cutoff (odd number)', ...
             %        'BackgroundColor', mean.fig.Color,...
             %        'Position', [50 65 140 39],...  % Adjust position and size accordingly
             %        'FontSize', 10,...
             %        'FontColor', white_text);
             % 
             %    % Input for filter cutoff
             %    cutoff = uieditfield(mean.fig, ...
             %        'numeric', ...
             %        'Position',[50 50 120 26],...
             %        'Value', 3, ...
             %        'BackgroundColor', [1 1 1]);
             % 
             %    % Apply button
             %    mean.apply = uibutton(mean.fig,...
             %        'Position', [250 20 80 30],...
             %        'Text', 'Apply', ...
             %        'BackgroundColor', green_btn, ...
             %        'FontColor', white_text, ...
             %        'ButtonPushedFcn', {@mean_apply});
             % 
             %    % Info button (adaptation needed for image handling)
             % 
             %   setappdata(0,'cutoff',cutoff)
            function mean_apply(varargin)
             cutoff=app.CutoffEditField.Value;
             matrices=getappdata(0,'myData');
             if isempty(matrices)
                return;
             end
             
            % matrices=getappdata(0,'myData');
            denoised=medfilt3(matrices.Images, [cutoff cutoff  1]);
            denoised(isnan(denoised))=0;
%             display_waitbar(1,1,90,100, waitbarHandle);
            
            matrices.Images=im2double(denoised);

            I=matrices.Images(:,:,5);
            axes(app.axes_Filter)
            imshow(I,[],'Parent', app.axes_Filter,'Colormap',jet);
            colormap(app.axes_Filter,getappdata(0,'color'));
            lower=round(min(matrices.Wavelengths));
            upper=round(max(matrices.Wavelengths));
            app.BandSlider_2.Limits =[lower upper];
            setappdata(0,'I',I) 
            setappdata(0,'myData',matrices);

            end
            function mean_spectral(varargin)
             cutoff=app.CutoffEditField.Value;
             matrices=getappdata(0,'myData');
             if isempty(matrices)
                return;
             end
             
            % matrices=getappdata(0,'myData');
            denoised=medfilt3(matrices.Images, [1 1 cutoff]);
            denoised(isnan(denoised))=0;
%             display_waitbar(1,1,90,100, waitbarHandle);
            
            matrices.Images=im2double(denoised);

            I=matrices.Images(:,:,5);
            axes(app.axes_Filter)
            imshow(I,[],'Parent', app.axes_Filter,'Colormap',jet);
            lower=round(min(matrices.Wavelengths));
            upper=round(max(matrices.Wavelengths));
            app.BandSlider_2.Limits =[lower upper];
            setappdata(0,'I',I) 
            setappdata(0,'myData',matrices);

             end
            function gaussian(varargin)
             % cutoff=app.CutoffEditField.Value;
             sigma=app.SigmaEditField.Value;
             filter=app.FilterSizeEditField.Value;
             matrices=getappdata(0,'myData');
             if isempty(matrices)
                return;
             end
             
            % matrices=getappdata(0,'myData');
            denoised = imgaussfilt3(matrices.Images, sigma, 'FilterSize', filter);
            denoised(isnan(denoised))=0;
%             display_waitbar(1,1,90,100, waitbarHandle);
            
            matrices.Images=im2double(denoised);

            I=matrices.Images(:,:,5);
            axes(app.axes_Filter)
            imshow(I,[],'Parent', app.axes_Filter,'Colormap',jet);
            lower=round(min(matrices.Wavelengths));
            upper=round(max(matrices.Wavelengths));
            app.BandSlider_2.Limits =[lower upper];
            setappdata(0,'I',I) 
            setappdata(0,'myData',matrices);

            end
        end

        % Menu selected function: SaveasidcubeformatMenu
        function SaveasidcubeformatMenuSelected(app, event)
            % idcubeoneHandle=getappdata(0,'idcubeoneHandle');
    matrices=getappdata(0,'myData');
    if isempty(matrices)
        return;
    end
    % 
    % N2=get(idcubeoneHandle.tabAnalysisMathematics,'value');
    % N3 = get(idcubeoneHandle.tabAnalysisExpression,'value'); % For mathematical expression
    % N4 = get(idcubeoneHandle.radioButtonRGBMode,'value');
    % N5 = get(idcubeoneHandle.tabAnalysisBroadBand,'value');
    % if   N2==1 || N3==1 
    %     matrices=getappdata(0,'myData_save');
    % end
     
    Image= matrices.Images;
%     figure;
%     imshow(Image(:,:,15))
%     colormap;
    wavelength = matrices.Wavelengths;
    %loaded = getappdata(0,'folders');
    pathstr = getappdata(0,'default_directory');
    filename = getappdata(0,'file_name');
%         [pathstr,filename] = fileparts(loaded{1});
    pathname = uigetdir(pathstr,'Select Destination Folder'); % Select destination folder [pathstr '/' filename]
%         foldername=[filename ' - PCA'];
    newfileName = [filename(1:end-4) '-data'  '.mat'];
    full_path = fullfile(pathname, newfileName);
%         mkdir([pathname '/' foldername]);
    im_obj = whos('Image');wave_obj = whos('wavelength');
    hsidata_size = (im_obj.bytes+wave_obj.bytes)/(1024^3);

    if hsidata_size>2
        save(full_path, 'Image', 'wavelength','-v7.3');
    else
        save(full_path, 'Image', 'wavelength');
    end
%     counter = counter + 1; setappdata(0,'mnfpcaExportCounter', counter);
%         save([pathname '/' foldername '/wavelength.mat'],'wavelength');
%         save([pathname '/' foldername '/Image.mat'],'Image','-v7.3'); %image file autosaves together


%% Change figure title icon
% folder = fileparts(mfilename('fullpath'));
% iconFilePath = fullfile(folder, 'Images', 'idcube-icon-transparent.png');
% setIcon(mnfH.figure, iconFilePath);

%msgbox(' datacube saved to directory')
folder = fileparts(mfilename('fullpath'));
iconFilePath = fullfile(folder, 'Images', 'idcube-icon-transparent.png');
setIcon(gcf, iconFilePath);
waitfor(setIcon(msgbox('Datacube Saved to Directory', 'Saving Datacube', 'help')))


%     catch
%         return
%     end
    
        end

        % Menu selected function: SaveasimageMenu
        function SaveasimageMenuSelected(app, event)
           
    [filename, pathname] = uiputfile({'*.png';'*.jpg';'*.tif'},'Save Image As...');
    temp = figure('visible','off');

%%
% Create a new figure (not visible)
temp = figure('visible', 'off');

% Get the image data from the original axes
temp_img = findall(app.image_axes, 'type', 'Image');
img_data = get(temp_img, 'CData');

% Create new axes in the temporary figure and plot the image data
axes_temp = axes('Parent', temp);
imshow(img_data, 'Parent', axes_temp);

% Remove ticks and labels
set(axes_temp, 'xtick', [], 'ytick', [], 'xticklabel', [], 'yticklabel', []);

% Set figure size to match the image size
truesize(temp, [size(img_data, 1) + 8, size(img_data, 2)]);

% Save the figure as an image
saveas(temp, fullfile(pathname, filename));


%%

%     return
% end
folder = fileparts(mfilename('fullpath'));
iconFilePath = fullfile(folder, 'Images', 'idcube-icon-transparent.png');
setIcon(gcf, iconFilePath);
(setIcon(msgbox({'The Image file has not been saved'}, 'Saving Image','help')));
        end

        % Callback function
        function PCAMenuSelected(app, event)
            % app.TabGroup.Visible='on'  ;
           % app.TabGroup.SelectedTab=app.PCATab;
           matrices = getappdata(0, 'myData');
           if isempty(matrices)
               return;
           end
           Images = matrices.Images;
           % set(app.ThreeD,'Visible','on')
           % set(app.image_axes,'Visible','off')
           % set(app.RemoveFilterButton,'Visible','on')
           num=app.NumberofPCAEditField.Value;
           app.CurrentAxes = app.axes_PCA;
           % set(gca, 'color', bgc);
           % set(gca, 'XColor', 'none', 'YColor', 'none', 'ZColor', 'none');
           [outputDataCube,~,~] = hyperpca(Images,num,'Method','Eig');
           lower=1;
           upper=num;
           app.Slider_PCA.Limits =[lower upper];
           app.Slider_PCA_1.Limits =[lower upper];
           app.Slider_PCA_2.Limits =[lower upper];
           app.Slider_PCA_3.Limits =[lower upper];
           I=outputDataCube(:,:,num);
           setappdata(0,'I',I)
           colormap(app.axes_PCA,jet)
           setappdata(0,'myDataPCA',outputDataCube)
           if app.ChannelSelectionButtonGroup_2.SelectedObject == app.SingleChannelButton_2
               UpdateImage.updateImage_PCA(app)
           else 
               UpdateImage.updateRGB_PCA(app)
           end
           % app.BandSlider.Visible='off';
           % set(app.BacktooriginalButton_2,'Visible','on')
           % h = load('trans0.mat');
           % y = struct2cell(h);
           %
           % hsidata = y{1};
           % y = struct2cell(hsidata);
           % alphamap = y{8};
           %
           %
           %
           % colormap2 = gray;
           %
           % opengl hardware;
           %
           % V = volshow(Images, 'Parent', app.ThreeD, 'Colormap', colormap2, 'Alphamap', alphamap);
           %
           %
           % VAxis = [0.2, 1.5];
           % setappdata(0, 'V', V);
           % % camtarget;
           % newscale = linspace(min(Images(:)) - min(VAxis(:)), ...
           %     max(Images(:)) - min(VAxis(:)), size(colormap2, 1)) / diff(VAxis);
           % newscale(newscale < 0) = 0;
           % newscale(newscale > 1) = 1;
           % V.Colormap = interp1(linspace(0, 1, size(colormap2, 1)), colormap2, newscale);
                  % threeD_plot8(1)

        end

        % Button pushed function: BacktooriginalButton_2
        function BacktooriginalButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.HomeTab;
        end

        % Value changed function: NumberofPCAEditField
        function NumberofPCAEditFieldValueChanged(app, event)
            PCAMenuSelected(app,event) %Callback of PCA
        end

        % Value changed function: Slider_PCA
        function Slider_PCAValueChanged(app, event)
            UpdateImage.updateImage_PCA(app)
        end

        % Drop down opening function: OPenFileTypeDropDown
        function OPenFileTypeDropDownOpening(app, event)
            OpenfileMenuSelected(app, event)
        end

        % Button pushed function: RemovefilterButton
        function RemovefilterButtonPushed(app, event)
            matrices=getappdata(0,'myDataOriginal');
            if isempty(matrices)
                return;
            end
            Image=im2double(matrices.Images);
            wavelength=matrices.Wavelengths ;
                    % display_waitbar(1,1,70,100, waitbarHandle);

            % set(app.ThreeD,'Visible','off')
            % set(app.image_axes,'Visible','on')      
            % set(app.RemoveFilterButton,'Visible','off')
                    %%

           
            %axes(app.image_axes)
            I=matrices.Images(:,:,2);
            axes(app.axes_Filter)
            imshow(I,[],'Parent', app.axes_Filter,'Colormap',jet);
            lower=round(min(matrices.Wavelengths));
            upper=round(max(matrices.Wavelengths));
            app.BandSlider_2.Limits =[lower upper];
            setappdata(0,'I',I) 
            setappdata(0,'myData',matrices);
        end

        % Value changed function: BandSlider_2
        function BandSlider_2ValueChanged(app, event)
            value = round(app.BandSlider_2.Value);
                        

               matrices=getappdata(0,'myData');
               if isempty(matrices)
                    return;
                end
             Image = im2double(matrices.Images );
                    % display_waitbar(1,1,70,100, waitbarHandle);
             I=Image(:,:,value);
            axes(app.axes_Filter)
            imshow(I,[],'Parent', app.axes_Filter,'Colormap',jet);
            
        end

        % Callback function
        function FilterTabButtonDown(app, event)
            matrices=getappdata(0,'myDataOriginal');
            if isempty(matrices)
                return;
            end
            Image=im2double(matrices.Images);
            wavelength=matrices.Wavelengths ;
                    % display_waitbar(1,1,70,100, waitbarHandle);

            % set(app.ThreeD,'Visible','off')
            % set(app.image_axes,'Visible','on')      
            % set(app.RemoveFilterButton,'Visible','off')
                    %%

           
            %axes(app.image_axes)
            I=matrices.Images(:,:,5);
            axes(app.axes_Filter)
            imshow(I,[],'Parent', app.axes_Filter,'Colormap',jet);
            lower=round(min(matrices.Wavelengths));
            upper=round(max(matrices.Wavelengths));
            app.BandSlider_2.Limits = [lower upper];
            setappdata(0,'I',I) 
            setappdata(0,'myData',matrices);
        end

        % Selection changed function: FiltergroupButtonGroup
        function filterSelectionChanged(app, event)
            selectedButton = app.FiltergroupButtonGroup.SelectedObject;
            switch selectedButton 
                case app.DgaussianButton
                    app.CutoffEditFieldLabel.Visible = "off";
                    app.CutoffEditField.Visible = "off";
                    app.SigmaEditFieldLabel.Visible = "on";
                    app.SigmaEditField.Visible = "on";
                    app.FilterSizeEditFieldLabel.Visible = "on";
                    app.FilterSizeEditField.Visible = "on";
                case app.MeanSpectralButton
                    app.CutoffEditFieldLabel.Visible = "on";
                    app.CutoffEditField.Visible = "on";
                    app.SigmaEditFieldLabel.Visible = "off";
                    app.SigmaEditField.Visible = "off";
                    app.FilterSizeEditFieldLabel.Visible = "off";
                    app.FilterSizeEditField.Visible = "off";
                case app.MeanSpatialButton
                    app.CutoffEditFieldLabel.Visible = "on";
                    app.CutoffEditField.Visible = "on";
                    app.SigmaEditFieldLabel.Visible = "off";
                    app.SigmaEditField.Visible = "off";
                    app.FilterSizeEditFieldLabel.Visible = "off";
                    app.FilterSizeEditField.Visible = "off";
            end
        end

        % Selection changed function: ChannelSelectionButtonGroup
        function channelSelectionChanged(app, event)
            selectedButton = app.ChannelSelectionButtonGroup.SelectedObject;
            switch selectedButton
                case app.SingleChannelButton
                    app.ColormapSelectionButtonGroup.Visible = "on";
                    app.BandSlider.Visible = "on";
                    app.Band1Slider.Visible = "off";
                    app.Band2Slider.Visible = "off";
                    app.Band3Slider.Visible = "off";
                    app.BandSliderLabel.Visible = "on";
                    app.Band1SliderLabel.Visible = "off";
                    app.Band2SliderLabel.Visible = "off";
                    app.Band3SliderLabel.Visible = "off";
                    UpdateImage.updateImage(app)
                    
                case app.RGBButton
                    app.BandSlider.Visible = "off";
                    app.Band1Slider.Visible = "on";
                    app.Band2Slider.Visible = "on";
                    app.Band3Slider.Visible = "on";
                    app.BandSliderLabel.Visible = "off";
                    app.Band1SliderLabel.Visible = "on";
                    app.Band2SliderLabel.Visible = "on";
                    app.Band3SliderLabel.Visible = "on";
                    
                    UpdateImage.updateRGB(app);
            end
        end

        % Value changed function: Band1Slider
        function Band1SliderChanged(app, event)
           UpdateImage.updateRGB(app);
        end

        % Value changed function: Band2Slider
        function Band2SliderChanged(app, event)
            UpdateImage.updateRGB(app);
        end

        % Value changed function: Band3Slider
        function Band3SliderChanged(app, event)
            UpdateImage.updateRGB(app);
        end

        % Selection changed function: ColormapSelectionButtonGroup
        function ColormapSelectionChanged(app, event)
            UpdateImage.updateImage(app)
        end

        % Button pushed function: GetDataButton
        function copyButtonPushed(app, event)

        hAxes = app.spectra;
        hLines = findobj(hAxes, 'Type', 'line');
        
        if isempty(hLines)
            return;
        end
        
        hLine_to_copy = hLines(1);
        x_values = hLine_to_copy.XData(:);
        y_values = hLine_to_copy.YData(:);
        
        % Get BrushData
        brushData = hLine_to_copy.BrushData;
        if ~isempty(brushData) && any(brushData)
            brushedIdx = brushData ~= 0;
            x_values = x_values(brushedIdx);
            y_values = y_values(brushedIdx);
        end
        
        % Format and copy
        data_strings = compose("%.15g\t%.15g", x_values, y_values);
        data_string = strjoin(data_strings, newline);
        app.DataTextArea.Value = data_string;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % 1. Get data
            matrices = getappdata(0, 'myData');
            if isempty(matrices)
                return;
            end
            currentImage = matrices.Images;
            [img_rows, img_cols, ~] = size(currentImage);

            % 2. Get inputs from EditFields
            x_coord = round(app.XEditField.Value);
            y_coord = round(app.YEditField.Value);
            width_val = app.WidthEditField.Value; % Might be [] if AllowEmpty is true
            height_val = app.HeightEditField.Value; % Might be []

            % Determine if an ROI is being specified (both width and height provided)
            is_roi_selection = ~isempty(width_val) && ~isempty(height_val);
            
            roi_width = [];
            roi_height = [];
            if is_roi_selection
                roi_width = round(width_val);
                roi_height = round(height_val);
            end

            % 3. Clear previous ROI/cross visual markers from app.image_axes and spectrum
            old_markers = findobj(app.image_axes.Children, 'Tag', 'ROIMarker');
            if ~isempty(old_markers)
                delete(old_markers);
            end
            cla(app.spectra); % Clear previous spectrum from the spectra UIAxes

            % 4. Validate inputs
            if isnan(x_coord) || isnan(y_coord) || x_coord < 1 || y_coord < 1 || x_coord > img_cols || y_coord > img_rows
                uialert(app.IDCubeProUIFigure, 'X/Y coordinates are out of image bounds or invalid.', 'Input Error', 'Icon', 'error');
                return;
            end

            if is_roi_selection
                if isnan(roi_width) || isnan(roi_height) || roi_width < 1 || roi_height < 1 || (x_coord + roi_width - 1) > img_cols || (y_coord + roi_height - 1) > img_rows
                    uialert(app.IDCubeProUIFigure, 'ROI dimensions are out of image bounds or invalid.', 'Input Error', 'Icon', 'error');
                    return;
                end
            end

            % 5. Draw ROI/Cross on app.image_axes
            hold(app.image_axes, 'on');
            if is_roi_selection
                rectangle(app.image_axes, 'Position', [x_coord, y_coord, roi_width, roi_height], 'EdgeColor', 'r', 'LineWidth', 1, 'Tag', 'ROIMarker');
                text(app.image_axes, x_coord, y_coord, sprintf('(%d,%d,%d,%d)', x_coord, y_coord, roi_width, roi_height), 'Color', 'r', 'FontSize', 12, 'VerticalAlignment', 'bottom', 'Tag', 'ROIMarker');
            else
                plot(app.image_axes, x_coord, y_coord, 'r+', 'MarkerSize', 10, 'LineWidth', 1, 'Tag', 'ROIMarker');
                text(app.image_axes, x_coord, y_coord, sprintf('(%d,%d)', x_coord, y_coord), 'Color', 'r', 'FontSize', 12, 'VerticalAlignment', 'bottom', 'Tag', 'ROIMarker');
            end
            hold(app.image_axes, 'off');

            % 6. Display Spectrum
            if is_roi_selection
                app.displaySpectrumFromROI(x_coord, y_coord, roi_width, roi_height);
            else
                app.displaySpectrumFromPoint(x_coord, y_coord);
            end
            
            app.XEditField.Visible = "off";
            app.XEditFieldLabel.Visible = "off";
            app.YEditField.Visible = "off";
            app.YEditFieldLabel.Visible = "off";
            app.WidthEditField.Visible = "off";
            app.WidthEditFieldLabel.Visible = "off";
            app.HeightEditField.Visible = "off";
            app.HeightEditFieldLabel.Visible = "off";
            app.OKButton.Visible = "off";
            app.CancelButton.Visible = "off";
            app.ExtractSpectrumButton.Enable = "on";
        end

        % Button pushed function: ExtractSpectrumButton
        function ExtractSpectrumButtonPushed(app, event)
            app.clearMultiSpectraElements();
            app.InteractiveTurnonButton.Enable = "on";
            app.IDCubeProUIFigure.WindowButtonMotionFcn = '';
            app.IDCubeProUIFigure.WindowButtonDownFcn = '';
            setptr(app.IDCubeProUIFigure, 'arrow');
            
            app.ClearButton.Visible = "off";
            app.OKButton_2.Visible = "off";
            app.MultiSpectraModeButton.Enable = "on";
            
            app.HeightEditFieldLabel.FontColor = "white";
            app.WidthEditFieldLabel.FontColor = "white";
            app.XEditFieldLabel.FontColor = "white";
            app.YEditFieldLabel.FontColor = "white";
            app.XEditField.Visible = "on";
            app.XEditFieldLabel.Visible = "on";
            app.YEditField.Visible = "on";
            app.YEditFieldLabel.Visible = "on";
            app.WidthEditField.Visible = "on";
            app.WidthEditFieldLabel.Visible = "on";
            app.HeightEditField.Visible = "on";
            app.HeightEditFieldLabel.Visible = "on";
            app.OKButton.Visible = "on";
            app.CancelButton.Visible = "on";

            matrices = getappdata(0, 'myData');
            if isempty(matrices)
                return;
            end
            currentImage = matrices.Images;
            [img_rows, img_cols, ~] = size(currentImage);
            mid_x_val = round(img_cols / 2);
            mid_y_val = round(img_rows / 2);
            app.XEditField.Value = mid_x_val;
            app.YEditField.Value = mid_y_val;
            app.WidthEditField.Value = [];
            app.HeightEditField.Value = [];
            app.ExtractSpectrumButton.Enable = "off";
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.XEditField.Visible = "off";
            app.XEditFieldLabel.Visible = "off";
            app.YEditField.Visible = "off";
            app.YEditFieldLabel.Visible = "off";
            app.WidthEditField.Visible = "off";
            app.WidthEditFieldLabel.Visible = "off";
            app.HeightEditField.Visible = "off";
            app.HeightEditFieldLabel.Visible = "off";
            app.OKButton.Visible = "off";
            app.CancelButton.Visible = "off";
            app.ExtractSpectrumButton.Enable = "on";
        end

        % Button pushed function: OKButton_2
        function OKButton2Pushed(app, event)
            app.MultiSpectraActive = false;
            app.IDCubeProUIFigure.WindowButtonDownFcn = '';
            app.IDCubeProUIFigure.WindowButtonMotionFcn = '';
            app.IDCubeProUIFigure.WindowButtonUpFcn = '';
            setptr(app.IDCubeProUIFigure, 'arrow');

            % Delete temporary drawing rectangle if it exists
            if ~isempty(app.MultiSpectraTempRectHandle) && isvalid(app.MultiSpectraTempRectHandle)
                delete(app.MultiSpectraTempRectHandle);
                app.MultiSpectraTempRectHandle = [];
            end

            app.ClearButton.Visible = "off";
            app.OKButton_2.Visible = "off";
            app.MultiSpectraModeButton.Enable = "on";
            app.ExtractSpectrumButton.Enable = "on"; % Re-enable single spectrum extraction
            app.InteractiveTurnonButton.Enable = "on"; % Re-enable interactive mode
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.clearMultiSpectraElements();
        end

        % Button pushed function: MultiSpectraModeButton
        function MultiSpectraPushed(app, event)
            setptr(app.IDCubeProUIFigure, 'crosshair');
            % Disable other interaction modes
            app.InteractiveTurnonButton.Enable = "on"; % Keep this enabled to switch out
            app.IDCubeProUIFigure.WindowButtonMotionFcn = ''; % Clear interactive mode listener
            % app.IDCubeProUIFigure.WindowButtonDownFcn = ''; % This will be set for multi-spectra

            app.ExtractSpectrumButton.Enable = "on"; % Keep this enabled to switch out
            app.XEditField.Visible = "off";
            app.XEditFieldLabel.Visible = "off";
            app.YEditField.Visible = "off";
            app.YEditFieldLabel.Visible = "off";
            app.WidthEditField.Visible = "off";
            app.WidthEditFieldLabel.Visible = "off";
            app.HeightEditField.Visible = "off";
            app.HeightEditFieldLabel.Visible = "off";
            app.OKButton.Visible = "off";
            app.CancelButton.Visible = "off";

            % Initialize Multi-Spectra Mode
            app.MultiSpectraActive = true;
            app.MultiSpectraCMap = lines(app.MultiSpectraMax); % Initialize colormap
            
            app.clearMultiSpectraElements(); % Clear any previous multi-spectra plots/ROIs
            
            % Set up figure for drawing ROIs
            app.IDCubeProUIFigure.WindowButtonDownFcn = @app.handleMultiSpectraButtonDown;
            % Motion and Up functions will be set by ButtonDown

            % Update UI for multi-spectra mode
            app.ClearButton.Visible = "on";
            app.OKButton_2.Visible = "on";
            app.MultiSpectraModeButton.Enable = "off"; % Disable self while active
        end

        % Selection changed function: ChannelSelectionButtonGroup_2
        function ChannelSelectionPCAChanged(app, event)
            selectedButton = app.ChannelSelectionButtonGroup_2.SelectedObject;
             switch selectedButton
                 case app.SingleChannelButton_2
                    app.Slider_PCA.Visible = "on";
                    app.Slider_PCA_1.Visible = "off";
                    app.Slider_PCA_2.Visible = "off";
                    app.Slider_PCA_3.Visible = "off";
                    app.SliderLabel.Visible = "on";
                    app.Slider1Label.Visible = "off";
                    app.Slider2Label.Visible = "off";
                    app.Slider3Label.Visible = "off";

                    UpdateImage.updateImage_PCA(app);
                    
                case app.RGBButton_2
                    app.Slider_PCA.Visible = "off";
                    app.Slider_PCA_1.Visible = "on";
                    app.Slider_PCA_2.Visible = "on";
                    app.Slider_PCA_3.Visible = "on";
                    app.SliderLabel.Visible = "off";
                    app.Slider1Label.Visible = "on";
                    app.Slider2Label.Visible = "on";
                    app.Slider3Label.Visible = "on";
                    
                    UpdateImage.updateRGB_PCA(app);
            end
        end

        % Value changed function: Slider_PCA_1
        function SliderPCA1Changed(app, event)
            UpdateImage.updateRGB_PCA(app);
            
        end

        % Value changed function: Slider_PCA_2
        function SliderPCA2Changed(app, event)
            UpdateImage.updateRGB_PCA(app);
            
        end

        % Value changed function: Slider_PCA_3
        function SliderPCA3Changed(app, event)
            UpdateImage.updateRGB_PCA(app);
            
        end

        % Selection change function: TabGroup
        function TabSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.PCATab
                    PCAMenuSelected(app, event)
                    
                case app.FilterTab
                    FilterTabButtonDown(app, event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IDCubeProUIFigure = uifigure('Visible', 'off');
            app.IDCubeProUIFigure.Name = 'IDCubePro';
            app.IDCubeProUIFigure.Icon = fullfile(pathToMLAPP, 'idcube-icon-transparent-new.png');
            app.TabGroup = uitabgroup(app.IDCubeProUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabSelectionChanged, true);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.image_axes = uiaxes(app.HomeTab);
            zlabel(app.image_axes, 'Z')
            app.image_axes.XTick = [];
            app.image_axes.XTickLabel = '';
            app.image_axes.YTick = [];
            app.image_axes.YTickLabel = '';
            app.spectra = uiaxes(app.HomeTab);
            title(app.spectra, '\color{white}Spectra')
            xlabel(app.spectra, 'Wavelength/Band')
            ylabel(app.spectra, 'Reflectance')
            zlabel(app.spectra, 'Z')
            app.InteractiveTurnonButton = uibutton(app.HomeTab, 'push');
            app.InteractiveTurnonButton.ButtonPushedFcn = createCallbackFcn(app, @InteractiveTurnonButtonPushed, true);
            app.InteractiveTurnonButton.Text = 'Interactive Turn on';
            app.BandSliderLabel = uilabel(app.HomeTab);
            app.BandSliderLabel.Text = 'Band';
            app.BandSlider = uislider(app.HomeTab);
            app.BandSlider.Limits = [1 100];
            app.BandSlider.ValueChangedFcn = createCallbackFcn(app, @BandSliderValueChanged, true);
            app.BandSlider.Value = 1;
            app.TextArea = uitextarea(app.HomeTab);
            app.OPenFileTypeDropDownLabel = uilabel(app.HomeTab);
            app.OPenFileTypeDropDownLabel.Text = 'Open File Type';
            app.OPenFileTypeDropDown = uidropdown(app.HomeTab);
            app.OPenFileTypeDropDown.Items = {'.mat', '.h5', '.nifti'};
            app.OPenFileTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OPenFileTypeDropDownOpening, true);
            app.OPenFileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @OpenfileMenuSelected, true);
            app.OPenFileTypeDropDown.Value = '.mat';
            app.OriginalImageLabel = uilabel(app.HomeTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.Band1SliderLabel = uilabel(app.HomeTab);
            app.Band1SliderLabel.Visible = 'off';
            app.Band1SliderLabel.Text = 'Band 1';
            app.Band1Slider = uislider(app.HomeTab);
            app.Band1Slider.Limits = [1 100];
            app.Band1Slider.ValueChangedFcn = createCallbackFcn(app, @Band1SliderChanged, true);
            app.Band1Slider.Visible = 'off';
            app.Band1Slider.Value = 1;
            app.Band2SliderLabel = uilabel(app.HomeTab);
            app.Band2SliderLabel.Visible = 'off';
            app.Band2SliderLabel.Text = 'Band 2';
            app.Band2Slider = uislider(app.HomeTab);
            app.Band2Slider.Limits = [1 100];
            app.Band2Slider.ValueChangedFcn = createCallbackFcn(app, @Band2SliderChanged, true);
            app.Band2Slider.Visible = 'off';
            app.Band2Slider.Value = 1;
            app.Band3SliderLabel = uilabel(app.HomeTab);
            app.Band3SliderLabel.Visible = 'off';
            app.Band3SliderLabel.Text = 'Band 3';
            app.Band3Slider = uislider(app.HomeTab);
            app.Band3Slider.Limits = [1 100];
            app.Band3Slider.ValueChangedFcn = createCallbackFcn(app, @Band3SliderChanged, true);
            app.Band3Slider.Visible = 'off';
            app.Band3Slider.Value = 1;
            app.ChannelSelectionButtonGroup = uibuttongroup(app.HomeTab);
            app.ChannelSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @channelSelectionChanged, true);
            app.ChannelSelectionButtonGroup.Title = 'Channel Selection';
            app.SingleChannelButton = uiradiobutton(app.ChannelSelectionButtonGroup);
            app.SingleChannelButton.Text = 'Single Channel';
            app.SingleChannelButton.Value = true;
            app.RGBButton = uiradiobutton(app.ChannelSelectionButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.ColormapSelectionButtonGroup = uibuttongroup(app.HomeTab);
            app.ColormapSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapSelectionChanged, true);
            app.ColormapSelectionButtonGroup.Title = 'Colormap Selection';
            app.jetButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.jetButton.Text = 'jet';
            app.jetButton.Value = true;
            app.autumnButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.flagButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.flagButton.Text = 'flag';
            app.boneButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.boneButton.Text = 'bone';
            app.coolButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.coolButton.Text = 'cool';
            app.copperButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.copperButton.Text = 'copper';
            app.grayButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.grayButton.Text = 'gray';
            app.hotButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.hotButton.Text = 'hot';
            app.pinkButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.pinkButton.Text = 'pink';
            app.springButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.springButton.Text = 'spring';
            app.summerButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.summerButton.Text = 'summer';
            app.winterButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.winterButton.Text = 'winter';
            app.hsvButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.hsvButton.Text = 'hsv';
            app.colorcubeButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.colorcubeButton.Text = 'colorcube';
            app.linesButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.linesButton.Text = 'lines';
            app.prismButton = uiradiobutton(app.ColormapSelectionButtonGroup);
            app.prismButton.Text = 'prism';
            app.GetDataButton = uibutton(app.HomeTab, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @copyButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.ExtractSpectrumButton = uibutton(app.HomeTab, 'push');
            app.ExtractSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSpectrumButtonPushed, true);
            app.ExtractSpectrumButton.Text = 'Extract Spectrum';
            app.XEditFieldLabel = uilabel(app.HomeTab);
            app.XEditFieldLabel.Visible = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.HomeTab, 'numeric');
            app.XEditField.Visible = 'off';
            app.YEditFieldLabel = uilabel(app.HomeTab);
            app.YEditFieldLabel.Visible = 'off';
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.HomeTab, 'numeric');
            app.YEditField.Visible = 'off';
            app.WidthEditFieldLabel = uilabel(app.HomeTab);
            app.WidthEditFieldLabel.Visible = 'off';
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.HomeTab, 'numeric');
            app.WidthEditField.AllowEmpty = 'on';
            app.WidthEditField.Visible = 'off';
            app.WidthEditField.Value = [];
            app.HeightEditFieldLabel = uilabel(app.HomeTab);
            app.HeightEditFieldLabel.Visible = 'off';
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.HomeTab, 'numeric');
            app.HeightEditField.AllowEmpty = 'on';
            app.HeightEditField.Visible = 'off';
            app.HeightEditField.Value = [];
            app.OKButton = uibutton(app.HomeTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Visible = 'off';
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.HomeTab, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            app.DataTextArea = uitextarea(app.HomeTab);
            app.MultiSpectraModeButton = uibutton(app.HomeTab, 'push');
            app.MultiSpectraModeButton.ButtonPushedFcn = createCallbackFcn(app, @MultiSpectraPushed, true);
            app.MultiSpectraModeButton.Text = 'Multi-Spectra Mode';
            app.ClearButton = uibutton(app.HomeTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Visible = 'off';
            app.ClearButton.Text = 'Clear';
            app.OKButton_2 = uibutton(app.HomeTab, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton2Pushed, true);
            app.OKButton_2.Visible = 'off';
            app.OKButton_2.Text = 'OK';
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.axes_PCA = uiaxes(app.PCATab);
            zlabel(app.axes_PCA, 'Z')
            app.axes_PCA.XTick = [];
            app.axes_PCA.YTick = [];
            app.NumberofPCAEditFieldLabel = uilabel(app.PCATab);
            app.NumberofPCAEditFieldLabel.Text = 'Number of PCA';
            app.NumberofPCAEditField = uieditfield(app.PCATab, 'numeric');
            app.NumberofPCAEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPCAEditFieldValueChanged, true);
            app.NumberofPCAEditField.Value = 5;
            app.Slider_PCA = uislider(app.PCATab);
            app.Slider_PCA.ValueChangedFcn = createCallbackFcn(app, @Slider_PCAValueChanged, true);
            app.Slider_PCA.Value = 1;
            app.SliderLabel = uilabel(app.PCATab);
            app.SliderLabel.Text = 'Slider';
            app.BacktooriginalButton_2 = uibutton(app.PCATab, 'push');
            app.BacktooriginalButton_2.ButtonPushedFcn = createCallbackFcn(app, @BacktooriginalButtonPushed, true);
            app.BacktooriginalButton_2.Text = 'Back to original';
            app.PCALabel = uilabel(app.PCATab);
            app.PCALabel.Text = 'PCA';
            app.ChannelSelectionButtonGroup_2 = uibuttongroup(app.PCATab);
            app.ChannelSelectionButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ChannelSelectionPCAChanged, true);
            app.ChannelSelectionButtonGroup_2.Title = 'Channel Selection';
            app.SingleChannelButton_2 = uiradiobutton(app.ChannelSelectionButtonGroup_2);
            app.SingleChannelButton_2.Text = 'Single Channel';
            app.SingleChannelButton_2.Value = true;
            app.RGBButton_2 = uiradiobutton(app.ChannelSelectionButtonGroup_2);
            app.RGBButton_2.Text = 'RGB';
            app.Slider1Label = uilabel(app.PCATab);
            app.Slider1Label.Visible = 'off';
            app.Slider1Label.Text = 'Slider 1';
            app.Slider_PCA_1 = uislider(app.PCATab);
            app.Slider_PCA_1.ValueChangedFcn = createCallbackFcn(app, @SliderPCA1Changed, true);
            app.Slider_PCA_1.Visible = 'off';
            app.Slider_PCA_1.Value = 1;
            app.Slider2Label = uilabel(app.PCATab);
            app.Slider2Label.Visible = 'off';
            app.Slider2Label.Text = 'Slider 2';
            app.Slider_PCA_2 = uislider(app.PCATab);
            app.Slider_PCA_2.ValueChangedFcn = createCallbackFcn(app, @SliderPCA2Changed, true);
            app.Slider_PCA_2.Visible = 'off';
            app.Slider_PCA_2.Value = 1;
            app.Slider3Label = uilabel(app.PCATab);
            app.Slider3Label.Visible = 'off';
            app.Slider3Label.Text = 'Slider 3';
            app.Slider_PCA_3 = uislider(app.PCATab);
            app.Slider_PCA_3.ValueChangedFcn = createCallbackFcn(app, @SliderPCA3Changed, true);
            app.Slider_PCA_3.Visible = 'off';
            app.Slider_PCA_3.Value = 1;
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.axes_Filter = uiaxes(app.FilterTab);
            title(app.axes_Filter, 'Filter')
            zlabel(app.axes_Filter, 'Z')
            app.axes_Filter.XTick = [];
            app.axes_Filter.YTick = [];
            app.Panel = uipanel(app.FilterTab);
            app.Panel.Title = 'Panel';
            app.CutoffEditFieldLabel = uilabel(app.Panel);
            app.CutoffEditFieldLabel.Text = 'Cut off';
            app.CutoffEditField = uieditfield(app.Panel, 'numeric');
            app.CutoffEditField.Value = 1;
            app.ApplyFilterButton = uibutton(app.Panel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanMenuSelected, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.FiltergroupButtonGroup = uibuttongroup(app.Panel);
            app.FiltergroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @filterSelectionChanged, true);
            app.FiltergroupButtonGroup.Title = 'Filter group';
            app.MeanSpatialButton = uiradiobutton(app.FiltergroupButtonGroup);
            app.MeanSpatialButton.Text = 'Mean Spatial';
            app.MeanSpatialButton.Value = true;
            app.MeanSpectralButton = uiradiobutton(app.FiltergroupButtonGroup);
            app.MeanSpectralButton.Text = 'Mean Spectral';
            app.DgaussianButton = uiradiobutton(app.FiltergroupButtonGroup);
            app.DgaussianButton.Text = '3-D gaussian';
            app.SigmaEditFieldLabel = uilabel(app.Panel);
            app.SigmaEditFieldLabel.Visible = 'off';
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.Panel, 'numeric');
            app.SigmaEditField.Visible = 'off';
            app.FilterSizeEditFieldLabel = uilabel(app.Panel);
            app.FilterSizeEditFieldLabel.Visible = 'off';
            app.FilterSizeEditFieldLabel.Text = 'Filter Size';
            app.FilterSizeEditField = uieditfield(app.Panel, 'numeric');
            app.FilterSizeEditField.Visible = 'off';
            app.RemovefilterButton = uibutton(app.Panel, 'push');
            app.RemovefilterButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefilterButtonPushed, true);
            app.RemovefilterButton.Text = 'Remove filter';
            app.BandSlider_2Label = uilabel(app.FilterTab);
            app.BandSlider_2Label.Text = 'Band';
            app.BandSlider_2 = uislider(app.FilterTab);
            app.BandSlider_2.ValueChangedFcn = createCallbackFcn(app, @BandSlider_2ValueChanged, true);
            app.BandSlider_2.Value = 1;
            app.ContextMenu = uicontextmenu(app.IDCubeProUIFigure);
            app.SaveasidcubeformatMenu = uimenu(app.ContextMenu);
            app.SaveasidcubeformatMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasidcubeformatMenuSelected, true);
            app.SaveasidcubeformatMenu.Text = 'Save as idcube format';
            app.SaveasimageMenu = uimenu(app.ContextMenu);
            app.SaveasimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasimageMenuSelected, true);
            app.SaveasimageMenu.Text = 'Save as image';
            % Assign app.ContextMenu
            app.image_axes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.IDCubeProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = web_app_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IDCubeProUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IDCubeProUIFigure)
        end
    end
end